classdef ProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        BasicTab                        matlab.ui.container.Tab
        SimpleSatellites                matlab.ui.control.NumericEditField
        SatellitesEditField_2Label      matlab.ui.control.Label
        SimpleEndAirport                matlab.ui.control.DropDown
        DestinationAirportDropDown_2Label  matlab.ui.control.Label
        SimpleEndCountry                matlab.ui.control.DropDown
        DestinationCountryDropDown_2Label  matlab.ui.control.Label
        SimpleStartAirport              matlab.ui.control.DropDown
        StartingAirportDropDown_2Label  matlab.ui.control.Label
        SimpleStartCountry              matlab.ui.control.DropDown
        StartingCountryDropDown_2Label  matlab.ui.control.Label
        SimpleSimulateButton            matlab.ui.control.Button
        AdvancedTab                     matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        TodayButton                     matlab.ui.control.Button
        StepTimeEditField               matlab.ui.control.NumericEditField
        StepTimeEditFieldLabel          matlab.ui.control.Label
        CreateSimulationButton          matlab.ui.control.Button
        DurationMinutesEditField        matlab.ui.control.NumericEditField
        DurationMinutesEditFieldLabel   matlab.ui.control.Label
        DurationHourEditField           matlab.ui.control.NumericEditField
        DurationHourEditFieldLabel      matlab.ui.control.Label
        StartMinuteEditField            matlab.ui.control.NumericEditField
        StartMinuteEditFieldLabel       matlab.ui.control.Label
        StartHourEditField              matlab.ui.control.NumericEditField
        StartHourEditFieldLabel         matlab.ui.control.Label
        StartDayEditField               matlab.ui.control.NumericEditField
        StartDayEditFieldLabel          matlab.ui.control.Label
        StartMonthEditField             matlab.ui.control.NumericEditField
        StartMonthEditFieldLabel        matlab.ui.control.Label
        StartYearEditField              matlab.ui.control.NumericEditField
        StartYearEditFieldLabel         matlab.ui.control.Label
        SatellitesTab                   matlab.ui.container.Tab
        CreateSatellitesButton          matlab.ui.control.Button
        WalkerTypeListBox               matlab.ui.control.ListBox
        WalkerTypeListBoxLabel          matlab.ui.control.Label
        InclinationEditField            matlab.ui.control.NumericEditField
        InclinationEditFieldLabel       matlab.ui.control.Label
        AltitudeEditField               matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel          matlab.ui.control.Label
        RelativeSpacingEditField        matlab.ui.control.NumericEditField
        RelativeSpacingEditFieldLabel   matlab.ui.control.Label
        PlanesEditField                 matlab.ui.control.NumericEditField
        PlanesEditFieldLabel            matlab.ui.control.Label
        SatellitesEditField             matlab.ui.control.NumericEditField
        SatellitesEditFieldLabel        matlab.ui.control.Label
        GroundStationsTab               matlab.ui.container.Tab
        RuntopologyButton               matlab.ui.control.Button
        FileSelectedTextArea            matlab.ui.control.TextArea
        FileSelectedTextAreaLabel       matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        RoutingTab                      matlab.ui.container.Tab
        DetermineRouteButton            matlab.ui.control.Button
        DestinationTypeDropDown         matlab.ui.control.DropDown
        DestinationTypeDropDownLabel    matlab.ui.control.Label
        OriginTypeDropDown              matlab.ui.control.DropDown
        OriginTypeDropDownLabel         matlab.ui.control.Label
        DestinationRouteDropDown        matlab.ui.control.DropDown
        DestinationDropDownLabel        matlab.ui.control.Label
        OriginDropDown                  matlab.ui.control.DropDown
        OriginDropDownLabel             matlab.ui.control.Label
        LinkTab                         matlab.ui.container.Tab
        ReceiverLossEditField           matlab.ui.control.NumericEditField
        ReceiverLossEditFieldLabel      matlab.ui.control.Label
        EstablishParameterButton        matlab.ui.control.Button
        ModulationIndexEditField        matlab.ui.control.NumericEditField
        ModulationIndexEditFieldLabel   matlab.ui.control.Label
        ModulationOrderListBox          matlab.ui.control.ListBox
        ModulationOrderListBoxLabel     matlab.ui.control.Label
        ModulationSchemeListBox         matlab.ui.control.ListBox
        ModulationSchemeListBoxLabel    matlab.ui.control.Label
        TransmitterGainEditField        matlab.ui.control.NumericEditField
        TransmitterGainEditFieldLabel   matlab.ui.control.Label
        SignalPowerEditField            matlab.ui.control.NumericEditField
        SignalPowerEditFieldLabel       matlab.ui.control.Label
        ChannelBandwidthEditField       matlab.ui.control.NumericEditField
        ChannelBandwidthEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        AccessTab                       matlab.ui.container.Tab
        ComparisonMetricListBox         matlab.ui.control.ListBox
        ComparisonMetricListBoxLabel    matlab.ui.control.Label
        CalculateLinkBudgetButton       matlab.ui.control.Button
        SimulateAccessButton            matlab.ui.control.Button
        CreateAccessButton              matlab.ui.control.Button
        DestinationAirportDropDown      matlab.ui.control.DropDown
        DestinationAirportDropDownLabel  matlab.ui.control.Label
        StartingAirportDropDown         matlab.ui.control.DropDown
        StartingAirportDropDownLabel    matlab.ui.control.Label
        CreateAircraftButton            matlab.ui.control.Button
        DisplayTypeListBox              matlab.ui.control.ListBox
        DisplayTypeListBoxLabel         matlab.ui.control.Label
        DestinationCountryDropDown      matlab.ui.control.DropDown
        DestinationCountryDropDownLabel  matlab.ui.control.Label
        StartingCountryDropDown         matlab.ui.control.DropDown
        StartingCountryDropDownLabel    matlab.ui.control.Label
        PauseTab                        matlab.ui.container.Tab
        PauseButton                     matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        DebugButton                     matlab.ui.control.Button
        CheckButton                     matlab.ui.control.Button
        DebugTextArea                   matlab.ui.control.TextArea
        DebugTextAreaLabel              matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    properties (Constant)
        airports = readtable("iata-icao.csv");
    end

    properties (Access = private)
        % Values
        countrycode_list = unique(ProjectGUI.airports.country_code);
        startcountry;
        startlat;
        startlong;
        endcountry;
        endlat;
        endlong;
        simstart;
        simduration;
        simsteptime;
        groundfile;
        figuretable;
        names;
        fig

        cfg = satelliteCNRConfig;

        satlist = [];
        gslist = [];

        % Objects
        scenario;
        constellation;
        aircraft;
        access;
        selective;
        groundstation;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartingCountryDropDown.Items = app.countrycode_list;
            app.DestinationCountryDropDown.Items = app.countrycode_list;
            app.fig = uifigure();
            app.figuretable = uitabgroup(app.fig, "Position", [1 1 1050 560]);
        end

        % Value changed function: StartingAirportDropDown
        function StartingAirportDropDownValueChanged(app, event)
            %value = app.StartingAirportDropDown.Value;
            
        end

        % Value changed function: StartingCountryDropDown
        function StartingCountryDropDownValueChanged(app, event)
            app.startcountry = string(app.countrycode_list(app.StartingCountryDropDown.ValueIndex));
            start_airport_list = app.airports(ismember(app.airports.country_code, app.startcountry), :);
            start_airport_names_list = table2cell([start_airport_list(:, [2 3])]);
            app.StartingAirportDropDown.Items = start_airport_names_list(:, 2);
        end

        % Drop down opening function: StartingCountryDropDown
        function StartingCountryDropDownOpening(app, event)
            
        end

        % Value changed function: DestinationCountryDropDown
        function DestinationCountryDropDownValueChanged(app, event)
            app.endcountry = string(app.countrycode_list(app.DestinationCountryDropDown.ValueIndex));
            end_airport_list = app.airports(ismember(app.airports.country_code, app.endcountry), :);
            end_airport_names_list = table2cell([end_airport_list(:, [2 3])]);
            app.DestinationAirportDropDown.Items = end_airport_names_list(:, 2);
        end

        % Button pushed function: CreateAircraftButton
        function CreateAircraftButtonPushed(app, event)
            start_airport_list = app.airports(ismember(app.airports.country_code, app.startcountry), :);
            end_airport_list = app.airports(ismember(app.airports.country_code, app.endcountry), :);
            
            app.startlat = table2array(start_airport_list(app.StartingAirportDropDown.ValueIndex, 4));
            app.startlong = table2array(start_airport_list(app.StartingAirportDropDown.ValueIndex, 5));
            app.endlat = table2array(end_airport_list(app.DestinationAirportDropDown.ValueIndex, 4));
            app.endlong = table2array(end_airport_list(app.DestinationAirportDropDown.ValueIndex, 5));
            
            app.aircraft = create_mission(app.scenario, app.simstart, app.simduration, app.startlat, app.startlong, app.endlat, app.endlong);
        end

        % Value changed function: DestinationAirportDropDown
        function DestinationAirportDropDownValueChanged(app, event)
            %value = app.DestinationAirportDropDown.Value;
        end

        % Value changed function: StartYearEditField
        function StartYearEditFieldValueChanged(app, event)
            value = app.StartYearEditField.Value;
            
        end

        % Button pushed function: CreateSimulationButton
        function CreateSimulationButtonPushed(app, event)
            simyear = app.StartYearEditField.Value;
            simmonth = app.StartMonthEditField.Value;
            simday = app.StartDayEditField.Value;
            simhour = app.StartHourEditField.Value;
            simmin = app.StartMinuteEditField.Value;
            app.simstart = datetime(simyear, simmonth, simday, simhour, simmin, 0);

            simdurationhour = app.DurationHourEditField.Value;
            simdurationminute = app.DurationMinutesEditField.Value;
            app.simduration = duration(simdurationhour, simdurationminute, 0);

            app.simsteptime = app.StepTimeEditField.Value;
            app.scenario = create_scenario(app.simstart, app.simduration, app.simsteptime);
        end

        % Button pushed function: CreateSatellitesButton
        function CreateSatellitesButtonPushed(app, event)
            app.constellation = walker_constellation_v2(app.scenario.scenario, app.SatellitesEditField.Value, app.PlanesEditField.Value, app.RelativeSpacingEditField.Value, app.AltitudeEditField.Value, app.InclinationEditField.Value, convertCharsToStrings(app.WalkerTypeListBox.Items));
            app.satlist = [app.constellation(1,1).Name];
            for i=2:app.SatellitesEditField.Value
                app.satlist(end+1) = app.constellation(1, i).Name;
            end
        end

        % Button pushed function: CreateAccessButton
        function CreateAccessButtonPushed(app, event)
            app.access = AccessAnalysis(app.scenario, app.aircraft, app.constellation, app.cfg.Frequency);
            
            % Add columns
            FSPLCol = zeros(height(app.access.Intervals), 1);
            app.access.Intervals.("FSPL") = FSPLCol;
            LatCol = zeros(height(app.access.Intervals), 1);
            app.access.Intervals.("Latency") = LatCol;
            BerCol = zeros(height(app.access.Intervals), 1);
            app.access.Intervals.("BER") = BerCol;
            SnrCol = zeros(height(app.access.Intervals), 1);
            app.access.Intervals.("SNR") = SnrCol;
        end

        % Button pushed function: SimulateAccessButton
        function SimulateAccessButtonPushed(app, event)
            if app.ComparisonMetricListBox.Value == "FSPL"
                app.selective = FSPLAccess(app.scenario, app.access, app.aircraft);
                figure2 = uitab(app.figuretable);
                uitable(figure2, 'Data',app.selective,'ColumnName',app.selective.Properties.VariableNames,...
    'RowName',app.selective.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
            end
            if app.ComparisonMetricListBox.Value == "SNR"
                app.selective = SNRAccess(app.scenario, app.access, app.aircraft);
                figure2 = uitab(app.figuretable);
                uitable(figure2, 'Data',app.selective,'ColumnName',app.selective.Properties.VariableNames,...
    'RowName',app.selective.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
            end
            if app.ComparisonMetricListBox.Value == "BER"
                app.selective = BERAccess(app.scenario, app.access, app.aircraft);
                figure2 = uitable(app.figuretable, 'Data',app.selective,'ColumnName',app.selective.Properties.VariableNames,...
    'RowName',app.selective.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
            end
            if app.ComparisonMetricListBox.Value == "Latency"
                app.selective = LatencyAccess(app.scenario, app.access, app.aircraft);
                figure2 = uitab(app.figuretable);
                uitable(figure2, 'Data',app.selective,'ColumnName',app.selective.Properties.VariableNames,...
    'RowName',app.selective.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
            end

            if app.DisplayTypeListBox.Value == convertStringsToChars('Red/Green')
                accessSim_red_green(app.scenario, app.selective, app.access)
            end
            if app.DisplayTypeListBox.Value == convertStringsToChars('Connected')
                accessSim_only_connected(app.scenario, app.selective, app.access)
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pausewarn = warndlg("Paused");
            waitfor(pausewarn)
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            [names,lats,longs,alts] = getCSVColumns(app.groundfile);
            app.DebugTextArea.Value = num2str(length(names))
            
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.groundfile = uigetfile();
            [~,name,~] = fileparts(app.groundfile);
            app.FileSelectedTextArea.Value = name;
        end

        % Callback function
        function CreateStationsButtonPushed(app, event)
            app.groundstation = groundstations(app.groundfile, app.scenario.scenario);
        end

        % Button pushed function: TodayButton
        function TodayButtonPushed(app, event)
            app.simstart = datetime("today");
            app.StartYearEditField.Value = app.simstart.Year;
            app.StartMonthEditField.Value = app.simstart.Month;
            app.StartDayEditField.Value = app.simstart.Day;
            app.StartHourEditField.Value = app.simstart.Hour;
            app.StartMinuteEditField.Value = app.simstart.Minute;
        end

        % Button pushed function: RuntopologyButton
        function RuntopologyButtonPushed(app, event)
            network_topology_with_many_stations(app.scenario.scenario, app.SatellitesEditField.Value, app.PlanesEditField.Value , app.groundfile)
        end

        % Button pushed function: EstablishParameterButton
        function EstablishParameterButtonPushed(app, event)
            app.cfg.TransmitterPower = app.SignalPowerEditField.Value;          % in dBW
            app.cfg.TransmitterSystemLoss = 3;      % in dB
            app.cfg.TransmitterAntennaGain = app.TransmitterGainEditField.Value;     % in dBi
            app.cfg.Frequency = app.CarrierFrequencyEditField.Value;                  % in GHz
            app.cfg.MiscellaneousLoss = 0;        % in dB
            app.cfg.GainToNoiseTemperatureRatio = app.cfg.TransmitterAntennaGain - 10*log10(290);   % in dB/K
            app.cfg.ReceiverSystemLoss = app.ReceiverLossEditField.Value;         % in dB
            app.cfg.Bandwidth = app.ChannelBandwidthEditField.Value;                      % in MHz
            app.cfg.BitRate = calculateBitRate(app.cfg.Bandwidth,app.ModulationSchemeListBox.Value,str2num(app.ModulationOrderListBox.Value),app.ModulationIndexEditField.Value);                   % in Mbps
        end

        % Button pushed function: CalculateLinkBudgetButton
        function CalculateLinkBudgetButtonPushed(app, event)
            for index = 1:size(app.access.Intervals.Source, 1)
                strippedsat = split(app.access.Intervals.Source(index, 1), '_'); % Get satellite number
                satnum = str2double(strippedsat(2));
                [latResults,berResults,snrResults,fsplResults,distance,timeSamples] = calculateLinkBudget(app.scenario.scenario.Satellites(1, satnum+1),app.aircraft.obj,app.cfg,app.ModulationSchemeListBox.Value,str2num(app.ModulationOrderListBox.Value),app.ModulationIndexEditField.Value,app.access.Intervals.StartTime(index),app.access.Intervals.Duration(index),app.simsteptime);
                avgFSPL = mean(fsplResults, 'all', 'omitnan');
                app.access.Intervals.FSPL(index,1) = avgFSPL;
                latResults = latResults(~isnan(latResults));
                avgLat = mean(latResults, "all", 'omitnan'); % Take average
                if isnan(avgLat)
                    avgLat = 0;
                end
                app.access.Intervals.Latency(index, 1) = avgLat; % Add average to access table
                avgBER = mean(berResults, "all", 'omitnan'); % Take average
                app.access.Intervals.BER(index, 1) = avgBER; % Add average to access table
                avgSNR = mean(snrResults, "all", 'omitnan'); % Take average
                app.access.Intervals.SNR(index, 1) = avgSNR; % Add average to access table
            end
            figure1 = uitab(app.figuretable);
            uitable(figure1, 'Data',app.access.Intervals,'ColumnName',app.access.Intervals.Properties.VariableNames,...
    'RowName',app.access.Intervals.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            % Sim
            app.simstart = datetime("today");
            app.StartYearEditField.Value = app.simstart.Year;
            app.StartMonthEditField.Value = app.simstart.Month;
            app.StartDayEditField.Value = app.simstart.Day;
            app.StartHourEditField.Value = app.simstart.Hour;
            app.StartMinuteEditField.Value = app.simstart.Minute;
            app.DurationHourEditField.Value = 3;
            app.DurationMinutesEditField.Value = 20;
            app.StepTimeEditField.Value = 60;

            %Satellite
            app.SatellitesEditField.Value = 72;
            app.PlanesEditField.Value = 6;
            app.RelativeSpacingEditField.Value = 1;
            app.AltitudeEditField.Value = 750;
            app.InclinationEditField.Value = 70;

            %Link
            app.CarrierFrequencyEditField.Value = 1.616;
            app.ChannelBandwidthEditField.Value = 0.0315;
            app.SignalPowerEditField.Value = 15;
            app.TransmitterGainEditField.Value = 5;
            app.TransmitterGainEditField.Value = 2;
            app.ReceiverLossEditField.Value = 2;
            
        end

        % Button pushed function: SimpleSimulateButton
        function SimpleSimulateButtonPushed(app, event)
            app.simstart = datetime("today");
            app.StartYearEditField.Value = app.simstart.Year;
            app.StartMonthEditField.Value = app.simstart.Month;
            app.StartDayEditField.Value = app.simstart.Day;
            app.StartHourEditField.Value = app.simstart.Hour;
            app.StartMinuteEditField.Value = app.simstart.Minute;
            app.DurationHourEditField.Value = 3;
            app.DurationMinutesEditField.Value = 20;
            app.StepTimeEditField.Value = 60;

            %Satellite
            app.SatellitesEditField.Value = 72;
            app.PlanesEditField.Value = 6;
            app.RelativeSpacingEditField.Value = 1;
            app.AltitudeEditField.Value = 750;
            app.InclinationEditField.Value = 70;

            %Link
            app.CarrierFrequencyEditField.Value = 1.616;
            app.ChannelBandwidthEditField.Value = 0.0315;
            app.SignalPowerEditField.Value = 15;
            app.TransmitterGainEditField.Value = 5;
            app.TransmitterGainEditField.Value = 2;
            app.ReceiverLossEditField.Value = 2;
            
            app.cfg.TransmitterPower = app.SignalPowerEditField.Value;          % in dBW
            app.cfg.TransmitterSystemLoss = 3;      % in dB
            app.cfg.TransmitterAntennaGain = app.TransmitterGainEditField.Value;     % in dBi
            app.cfg.Frequency = app.CarrierFrequencyEditField.Value;                  % in GHz
            app.cfg.MiscellaneousLoss = 0;        % in dB
            app.cfg.GainToNoiseTemperatureRatio = app.cfg.TransmitterAntennaGain - 10*log10(290);   % in dB/K
            app.cfg.ReceiverSystemLoss = app.ReceiverLossEditField.Value;         % in dB
            app.cfg.Bandwidth = app.ChannelBandwidthEditField.Value;                      % in MHz
            app.cfg.BitRate = calculateBitRate(app.cfg.Bandwidth,"QAM",4,app.ModulationIndexEditField.Value);          

            %Simulation
            simyear = app.StartYearEditField.Value;
            simmonth = app.StartMonthEditField.Value;
            simday = app.StartDayEditField.Value;
            simhour = app.StartHourEditField.Value;
            simmin = app.StartMinuteEditField.Value;
            app.simstart = datetime(simyear, simmonth, simday, simhour, simmin, 0);

            simdurationhour = app.DurationHourEditField.Value;
            simdurationminute = app.DurationMinutesEditField.Value;
            app.simduration = duration(simdurationhour, simdurationminute, 0);

            app.simsteptime = app.StepTimeEditField.Value;
            app.scenario = create_scenario(app.simstart, app.simduration, app.simsteptime);
            
            %Satellites
            app.constellation = walker_constellation_v2(app.scenario.scenario, app.SimpleSatellites.Value, app.PlanesEditField.Value, app.RelativeSpacingEditField.Value, app.AltitudeEditField.Value, app.InclinationEditField.Value, convertCharsToStrings(app.WalkerTypeListBox.Items));


            %Aircraft
            start_airport_list = app.airports(ismember(app.airports.country_code, app.startcountry), :);
            end_airport_list = app.airports(ismember(app.airports.country_code, app.endcountry), :);
            
            app.startlat = table2array(start_airport_list(app.SimpleStartAirport.ValueIndex, 4));
            app.startlong = table2array(start_airport_list(app.SimpleStartAirport.ValueIndex, 5));
            app.endlat = table2array(end_airport_list(app.SimpleEndAirport.ValueIndex, 4));
            app.endlong = table2array(end_airport_list(app.SimpleEndAirport.ValueIndex, 5));
            
            app.aircraft = create_mission(app.scenario, app.simstart, app.simduration, app.startlat, app.startlong, app.endlat, app.endlong);
            
            %Access
            app.access = AccessAnalysis(app.scenario, app.aircraft, app.constellation, app.cfg.Frequency);
            FSPLCol = zeros(height(app.access.Intervals), 1);
            app.access.Intervals.("FSPL") = FSPLCol;
            LatCol = zeros(height(app.access.Intervals), 1);
            app.access.Intervals.("Latency") = LatCol;
            BerCol = zeros(height(app.access.Intervals), 1);
            app.access.Intervals.("BER") = BerCol;
            SnrCol = zeros(height(app.access.Intervals), 1);
            app.access.Intervals.("SNR") = SnrCol;

            %Calculating Link
            for index = 1:size(app.access.Intervals.Source, 1)
                strippedsat = split(app.access.Intervals.Source(index, 1), '_'); % Get satellite number
                satnum = str2double(strippedsat(2));
                [latResults,berResults,snrResults,fsplResults,distance,timeSamples] = calculateLinkBudget(app.scenario.scenario.Satellites(1, satnum+1),app.aircraft.obj,app.cfg,app.ModulationSchemeListBox.Value,str2num(app.ModulationOrderListBox.Value),app.ModulationIndexEditField.Value,app.access.Intervals.StartTime(index),app.access.Intervals.Duration(index),app.simsteptime);
                avgFSPL = mean(fsplResults, 'all', 'omitnan');
                app.access.Intervals.FSPL(index,1) = avgFSPL;
                latResults = latResults(~isnan(latResults));
                avgLat = mean(latResults, "all", 'omitnan'); % Take average
                if isnan(avgLat)
                    avgLat = 0;
                end
                app.access.Intervals.Latency(index, 1) = avgLat; % Add average to access table
                avgBER = mean(berResults, "all", 'omitnan'); % Take average
                app.access.Intervals.BER(index, 1) = avgBER; % Add average to access table
                avgSNR = mean(snrResults, "all", 'omitnan'); % Take average
                app.access.Intervals.SNR(index, 1) = avgSNR; % Add average to access table
            end

            app.figuretable = uitabgroup();
            figure1 = uitab(app.figuretable, 'Title', "Original", 'Data',app.access.Intervals,'ColumnName',app.access.Intervals.Properties.VariableNames,...
            'RowName',app.access.Intervals.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);

            %Simulate
            app.selective = SNRAccess(app.scenario, app.access, app.aircraft);
            figure2 = uitable(app.figuretable, 'Title', "Selective", 'Data',app.selective,'ColumnName',app.selective.Properties.VariableNames,...
            'RowName',app.selective.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
            accessSim_red_green(app.scenario, app.selective, app.access)
        end

        % Value changed function: SimpleStartCountry
        function SimpleStartCountryValueChanged(app, event)
            app.startcountry = string(app.countrycode_list(app.StartingCountryDropDown.ValueIndex));
            start_airport_list = app.airports(ismember(app.airports.country_code, app.startcountry), :);
            start_airport_names_list = table2cell([start_airport_list(:, [2 3])]);
            app.SimpleStartAirport.Items = start_airport_names_list(:, 2);
        end

        % Value changed function: SimpleStartAirport
        function SimpleStartAirportValueChanged(app, event)
            value = app.SimpleStartAirport.Value;
            
        end

        % Value changed function: SimpleEndCountry
        function SimpleEndCountryValueChanged(app, event)
            app.endcountry = string(app.countrycode_list(app.DestinationCountryDropDown.ValueIndex));
            end_airport_list = app.airports(ismember(app.airports.country_code, app.endcountry), :);
            end_airport_names_list = table2cell([end_airport_list(:, [2 3])]);
            app.DestinationAirportDropDown.Items = end_airport_names_list(:, 2);
        end

        % Value changed function: SimpleEndAirport
        function SimpleEndAirportValueChanged(app, event)

        end

        % Value changed function: OriginTypeDropDown
        function OriginTypeDropDownValueChanged(app, event)
            if(app.OriginTypeDropDown.Value == "Satellite")
                app.OriginDropDown.Items = app.satlist;
            else
                [names,~,~,~] = getCSVColumns(app.groundfile);
                numbers = 1:length(names);
                numbers = transpose(numbers);
                app.OriginDropDown.Items = string(numbers);
            end
        end

        % Value changed function: DestinationTypeDropDown
        function DestinationTypeDropDownValueChanged(app, event)
            if(app.DestinationTypeDropDown.Value == "Satellite")
                app.DestinationRouteDropDown.Items = app.satlist;
            else
                [names,~,~,~] = getCSVColumns(app.groundfile);
                numbers = 1:length(names);
                numbers = transpose(numbers);
                app.DestinationRouteDropDown.Items = string(numbers);
            end
        end

        % Button pushed function: DetermineRouteButton
        function DetermineRouteButtonPushed(app, event)
            if(app.OriginTypeDropDown.ValueIndex == 1)
                if(app.DestinationTypeDropDown.ValueIndex == 1)
                    st_types = 0;
                else 
                    st_types = 3;
                end
            elseif(app.OriginTypeDropDown.ValueIndex == 2)
                if(app.DestinationTypeDropDown.ValueIndex == 1)
                    st_types = 2;
                else 
                    st_types = 1;
                end
            else
                st_types = 1;
            end
            
            
            [accumulatedTable, validLinks] = network_path_opt(app.scenario.scenario, app.constellation, app.PlanesEditField.Value, app.groundfile, app.OriginDropDown.ValueIndex, app.DestinationRouteDropDown.ValueIndex, st_types, 600);
            figure3 = uitab(app.figuretable);
                uitable(figure3, 'Data',accumulatedTable);
            routingaccess = struct();
            routingaccess.obj = app.scenario.scenario.Accesses;
            updatePathTime(app.scenario.scenario,accumulatedTable,routingaccess.obj);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup2);
            app.BasicTab.Title = 'Basic';
            app.SimpleSimulateButton = uibutton(app.BasicTab, 'push');
            app.SimpleSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleSimulateButtonPushed, true);
            app.SimpleSimulateButton.Text = 'Simulate';
            app.StartingCountryDropDown_2Label = uilabel(app.BasicTab);
            app.StartingCountryDropDown_2Label.Text = 'Starting Country';
            app.SimpleStartCountry = uidropdown(app.BasicTab);
            app.SimpleStartCountry.Items = {};
            app.SimpleStartCountry.Editable = 'on';
            app.SimpleStartCountry.ValueChangedFcn = createCallbackFcn(app, @SimpleStartCountryValueChanged, true);
            app.SimpleStartCountry.Value = {};
            app.StartingAirportDropDown_2Label = uilabel(app.BasicTab);
            app.StartingAirportDropDown_2Label.Text = 'Starting Airport';
            app.SimpleStartAirport = uidropdown(app.BasicTab);
            app.SimpleStartAirport.Items = {};
            app.SimpleStartAirport.Editable = 'on';
            app.SimpleStartAirport.ValueChangedFcn = createCallbackFcn(app, @SimpleStartAirportValueChanged, true);
            app.SimpleStartAirport.Value = {};
            app.DestinationCountryDropDown_2Label = uilabel(app.BasicTab);
            app.DestinationCountryDropDown_2Label.Text = 'Destination Country';
            app.SimpleEndCountry = uidropdown(app.BasicTab);
            app.SimpleEndCountry.Items = {};
            app.SimpleEndCountry.Editable = 'on';
            app.SimpleEndCountry.ValueChangedFcn = createCallbackFcn(app, @SimpleEndCountryValueChanged, true);
            app.SimpleEndCountry.Value = {};
            app.DestinationAirportDropDown_2Label = uilabel(app.BasicTab);
            app.DestinationAirportDropDown_2Label.Text = 'Destination Airport';
            app.SimpleEndAirport = uidropdown(app.BasicTab);
            app.SimpleEndAirport.Items = {};
            app.SimpleEndAirport.Editable = 'on';
            app.SimpleEndAirport.ValueChangedFcn = createCallbackFcn(app, @SimpleEndAirportValueChanged, true);
            app.SimpleEndAirport.Value = {};
            app.SatellitesEditField_2Label = uilabel(app.BasicTab);
            app.SatellitesEditField_2Label.Text = 'Satellites';
            app.SimpleSatellites = uieditfield(app.BasicTab, 'numeric');
            app.AdvancedTab = uitab(app.TabGroup2);
            app.AdvancedTab.Title = 'Advanced';
            app.TabGroup = uitabgroup(app.AdvancedTab);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.StartYearEditFieldLabel = uilabel(app.SimulationTab);
            app.StartYearEditFieldLabel.Text = 'Start Year';
            app.StartYearEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StartYearEditField.ValueChangedFcn = createCallbackFcn(app, @StartYearEditFieldValueChanged, true);
            app.StartMonthEditFieldLabel = uilabel(app.SimulationTab);
            app.StartMonthEditFieldLabel.Text = 'Start Month';
            app.StartMonthEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StartDayEditFieldLabel = uilabel(app.SimulationTab);
            app.StartDayEditFieldLabel.Text = 'Start Day';
            app.StartDayEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StartHourEditFieldLabel = uilabel(app.SimulationTab);
            app.StartHourEditFieldLabel.Text = 'Start Hour';
            app.StartHourEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StartMinuteEditFieldLabel = uilabel(app.SimulationTab);
            app.StartMinuteEditFieldLabel.Text = 'Start Minute';
            app.StartMinuteEditField = uieditfield(app.SimulationTab, 'numeric');
            app.DurationHourEditFieldLabel = uilabel(app.SimulationTab);
            app.DurationHourEditFieldLabel.Text = 'Duration Hour';
            app.DurationHourEditField = uieditfield(app.SimulationTab, 'numeric');
            app.DurationMinutesEditFieldLabel = uilabel(app.SimulationTab);
            app.DurationMinutesEditFieldLabel.Text = 'Duration Minutes';
            app.DurationMinutesEditField = uieditfield(app.SimulationTab, 'numeric');
            app.CreateSimulationButton = uibutton(app.SimulationTab, 'push');
            app.CreateSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSimulationButtonPushed, true);
            app.CreateSimulationButton.Text = 'Create Simulation';
            app.StepTimeEditFieldLabel = uilabel(app.SimulationTab);
            app.StepTimeEditFieldLabel.Text = 'Step Time';
            app.StepTimeEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TodayButton = uibutton(app.SimulationTab, 'push');
            app.TodayButton.ButtonPushedFcn = createCallbackFcn(app, @TodayButtonPushed, true);
            app.TodayButton.Text = 'Today';
            app.SatellitesTab = uitab(app.TabGroup);
            app.SatellitesTab.Title = 'Satellites';
            app.SatellitesEditFieldLabel = uilabel(app.SatellitesTab);
            app.SatellitesEditFieldLabel.Text = 'Satellites';
            app.SatellitesEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.PlanesEditFieldLabel = uilabel(app.SatellitesTab);
            app.PlanesEditFieldLabel.Text = 'Planes';
            app.PlanesEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.RelativeSpacingEditFieldLabel = uilabel(app.SatellitesTab);
            app.RelativeSpacingEditFieldLabel.Text = 'Relative Spacing';
            app.RelativeSpacingEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.AltitudeEditFieldLabel = uilabel(app.SatellitesTab);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.InclinationEditFieldLabel = uilabel(app.SatellitesTab);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.WalkerTypeListBoxLabel = uilabel(app.SatellitesTab);
            app.WalkerTypeListBoxLabel.Text = 'Walker Type';
            app.WalkerTypeListBox = uilistbox(app.SatellitesTab);
            app.WalkerTypeListBox.Items = {'delta', 'star'};
            app.WalkerTypeListBox.Value = 'delta';
            app.CreateSatellitesButton = uibutton(app.SatellitesTab, 'push');
            app.CreateSatellitesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSatellitesButtonPushed, true);
            app.CreateSatellitesButton.Text = 'Create Satellites';
            app.GroundStationsTab = uitab(app.TabGroup);
            app.GroundStationsTab.Title = 'Ground Stations';
            app.UploadButton = uibutton(app.GroundStationsTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FileSelectedTextAreaLabel = uilabel(app.GroundStationsTab);
            app.FileSelectedTextAreaLabel.Text = 'File Selected';
            app.FileSelectedTextArea = uitextarea(app.GroundStationsTab);
            app.RuntopologyButton = uibutton(app.GroundStationsTab, 'push');
            app.RuntopologyButton.ButtonPushedFcn = createCallbackFcn(app, @RuntopologyButtonPushed, true);
            app.RuntopologyButton.Text = 'Run topology';
            app.RoutingTab = uitab(app.TabGroup);
            app.RoutingTab.Title = 'Routing';
            app.OriginDropDownLabel = uilabel(app.RoutingTab);
            app.OriginDropDownLabel.Text = 'Origin';
            app.OriginDropDown = uidropdown(app.RoutingTab);
            app.DestinationDropDownLabel = uilabel(app.RoutingTab);
            app.DestinationDropDownLabel.Text = 'Destination';
            app.DestinationRouteDropDown = uidropdown(app.RoutingTab);
            app.OriginTypeDropDownLabel = uilabel(app.RoutingTab);
            app.OriginTypeDropDownLabel.Text = 'Origin Type';
            app.OriginTypeDropDown = uidropdown(app.RoutingTab);
            app.OriginTypeDropDown.Items = {'Satellite', 'Ground Station'};
            app.OriginTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @OriginTypeDropDownValueChanged, true);
            app.OriginTypeDropDown.Value = 'Satellite';
            app.DestinationTypeDropDownLabel = uilabel(app.RoutingTab);
            app.DestinationTypeDropDownLabel.Text = 'Destination Type';
            app.DestinationTypeDropDown = uidropdown(app.RoutingTab);
            app.DestinationTypeDropDown.Items = {'Satellite', 'Ground Station'};
            app.DestinationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DestinationTypeDropDownValueChanged, true);
            app.DestinationTypeDropDown.Value = 'Satellite';
            app.DetermineRouteButton = uibutton(app.RoutingTab, 'push');
            app.DetermineRouteButton.ButtonPushedFcn = createCallbackFcn(app, @DetermineRouteButtonPushed, true);
            app.DetermineRouteButton.Text = 'Determine Route';
            app.LinkTab = uitab(app.TabGroup);
            app.LinkTab.Title = 'Link';
            app.CarrierFrequencyEditFieldLabel = uilabel(app.LinkTab);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.LinkTab, 'numeric');
            app.ChannelBandwidthEditFieldLabel = uilabel(app.LinkTab);
            app.ChannelBandwidthEditFieldLabel.Text = 'Channel Bandwidth';
            app.ChannelBandwidthEditField = uieditfield(app.LinkTab, 'numeric');
            app.SignalPowerEditFieldLabel = uilabel(app.LinkTab);
            app.SignalPowerEditFieldLabel.Text = 'Signal Power';
            app.SignalPowerEditField = uieditfield(app.LinkTab, 'numeric');
            app.TransmitterGainEditFieldLabel = uilabel(app.LinkTab);
            app.TransmitterGainEditFieldLabel.Text = 'Transmitter Gain';
            app.TransmitterGainEditField = uieditfield(app.LinkTab, 'numeric');
            app.ModulationSchemeListBoxLabel = uilabel(app.LinkTab);
            app.ModulationSchemeListBoxLabel.Text = 'Modulation Scheme';
            app.ModulationSchemeListBox = uilistbox(app.LinkTab);
            app.ModulationSchemeListBox.Items = {'QAM', 'PSK', 'FSK'};
            app.ModulationSchemeListBox.Value = 'QAM';
            app.ModulationOrderListBoxLabel = uilabel(app.LinkTab);
            app.ModulationOrderListBoxLabel.Text = 'Modulation Order';
            app.ModulationOrderListBox = uilistbox(app.LinkTab);
            app.ModulationOrderListBox.Items = {'2', '4', '8', '16', '32'};
            app.ModulationOrderListBox.Value = '2';
            app.ModulationIndexEditFieldLabel = uilabel(app.LinkTab);
            app.ModulationIndexEditFieldLabel.Text = 'Modulation Index';
            app.ModulationIndexEditField = uieditfield(app.LinkTab, 'numeric');
            app.EstablishParameterButton = uibutton(app.LinkTab, 'push');
            app.EstablishParameterButton.ButtonPushedFcn = createCallbackFcn(app, @EstablishParameterButtonPushed, true);
            app.EstablishParameterButton.Text = 'Establish Parameter';
            app.ReceiverLossEditFieldLabel = uilabel(app.LinkTab);
            app.ReceiverLossEditFieldLabel.Text = 'Receiver Loss';
            app.ReceiverLossEditField = uieditfield(app.LinkTab, 'numeric');
            app.AccessTab = uitab(app.TabGroup);
            app.AccessTab.Title = 'Access';
            app.StartingCountryDropDownLabel = uilabel(app.AccessTab);
            app.StartingCountryDropDownLabel.Text = 'Starting Country';
            app.StartingCountryDropDown = uidropdown(app.AccessTab);
            app.StartingCountryDropDown.Items = {};
            app.StartingCountryDropDown.Editable = 'on';
            app.StartingCountryDropDown.DropDownOpeningFcn = createCallbackFcn(app, @StartingCountryDropDownOpening, true);
            app.StartingCountryDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingCountryDropDownValueChanged, true);
            app.StartingCountryDropDown.Value = {};
            app.DestinationCountryDropDownLabel = uilabel(app.AccessTab);
            app.DestinationCountryDropDownLabel.Text = 'Destination Country';
            app.DestinationCountryDropDown = uidropdown(app.AccessTab);
            app.DestinationCountryDropDown.Items = {};
            app.DestinationCountryDropDown.Editable = 'on';
            app.DestinationCountryDropDown.ValueChangedFcn = createCallbackFcn(app, @DestinationCountryDropDownValueChanged, true);
            app.DestinationCountryDropDown.Value = {};
            app.DisplayTypeListBoxLabel = uilabel(app.AccessTab);
            app.DisplayTypeListBoxLabel.Text = 'Display Type';
            app.DisplayTypeListBox = uilistbox(app.AccessTab);
            app.DisplayTypeListBox.Items = {'Red/Green', 'Connected'};
            app.DisplayTypeListBox.Value = {};
            app.CreateAircraftButton = uibutton(app.AccessTab, 'push');
            app.CreateAircraftButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAircraftButtonPushed, true);
            app.CreateAircraftButton.Text = 'Create Aircraft';
            app.StartingAirportDropDownLabel = uilabel(app.AccessTab);
            app.StartingAirportDropDownLabel.Text = 'Starting Airport';
            app.StartingAirportDropDown = uidropdown(app.AccessTab);
            app.StartingAirportDropDown.Items = {};
            app.StartingAirportDropDown.Editable = 'on';
            app.StartingAirportDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingAirportDropDownValueChanged, true);
            app.StartingAirportDropDown.Value = {};
            app.DestinationAirportDropDownLabel = uilabel(app.AccessTab);
            app.DestinationAirportDropDownLabel.Text = 'Destination Airport';
            app.DestinationAirportDropDown = uidropdown(app.AccessTab);
            app.DestinationAirportDropDown.Items = {};
            app.DestinationAirportDropDown.Editable = 'on';
            app.DestinationAirportDropDown.ValueChangedFcn = createCallbackFcn(app, @DestinationAirportDropDownValueChanged, true);
            app.DestinationAirportDropDown.Value = {};
            app.CreateAccessButton = uibutton(app.AccessTab, 'push');
            app.CreateAccessButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccessButtonPushed, true);
            app.CreateAccessButton.Text = 'Create Access';
            app.SimulateAccessButton = uibutton(app.AccessTab, 'push');
            app.SimulateAccessButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateAccessButtonPushed, true);
            app.SimulateAccessButton.Text = 'Simulate Access';
            app.CalculateLinkBudgetButton = uibutton(app.AccessTab, 'push');
            app.CalculateLinkBudgetButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateLinkBudgetButtonPushed, true);
            app.CalculateLinkBudgetButton.Text = 'Calculate Link Budget';
            app.ComparisonMetricListBoxLabel = uilabel(app.AccessTab);
            app.ComparisonMetricListBoxLabel.Text = 'Comparison Metric';
            app.ComparisonMetricListBox = uilistbox(app.AccessTab);
            app.ComparisonMetricListBox.Items = {'FSPL', 'SNR', 'BER', 'Latency'};
            app.ComparisonMetricListBox.Value = 'FSPL';
            app.PauseTab = uitab(app.TabGroup);
            app.PauseTab.Title = 'Pause';
            app.PauseButton = uibutton(app.PauseTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.DebugTextAreaLabel = uilabel(app.Tab);
            app.DebugTextAreaLabel.Text = 'Debug';
            app.DebugTextArea = uitextarea(app.Tab);
            app.CheckButton = uibutton(app.Tab, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.DebugButton = uibutton(app.Tab, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end