classdef apptest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        StepTimeEditField               matlab.ui.control.NumericEditField
        StepTimeEditFieldLabel          matlab.ui.control.Label
        CreateSimulationButton          matlab.ui.control.Button
        DurationMinutesEditField        matlab.ui.control.NumericEditField
        DurationMinutesEditFieldLabel   matlab.ui.control.Label
        DurationHourEditField           matlab.ui.control.NumericEditField
        DurationHourEditFieldLabel      matlab.ui.control.Label
        StartMinuteEditField            matlab.ui.control.NumericEditField
        StartMinuteEditFieldLabel       matlab.ui.control.Label
        StartHourEditField              matlab.ui.control.NumericEditField
        StartHourEditFieldLabel         matlab.ui.control.Label
        StartDayEditField               matlab.ui.control.NumericEditField
        StartDayEditFieldLabel          matlab.ui.control.Label
        StartMonthEditField             matlab.ui.control.NumericEditField
        StartMonthEditFieldLabel        matlab.ui.control.Label
        StartYearEditField              matlab.ui.control.NumericEditField
        StartYearEditFieldLabel         matlab.ui.control.Label
        SatellitesTab                   matlab.ui.container.Tab
        CreateSatellitesButton          matlab.ui.control.Button
        WalkerTypeListBox               matlab.ui.control.ListBox
        WalkerTypeListBoxLabel          matlab.ui.control.Label
        InclinationEditField            matlab.ui.control.NumericEditField
        InclinationEditFieldLabel       matlab.ui.control.Label
        AltitudeEditField               matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel          matlab.ui.control.Label
        RelativeSpacingEditField        matlab.ui.control.NumericEditField
        RelativeSpacingEditFieldLabel   matlab.ui.control.Label
        PlanesEditField                 matlab.ui.control.NumericEditField
        PlanesEditFieldLabel            matlab.ui.control.Label
        SatellitesEditField             matlab.ui.control.NumericEditField
        SatellitesEditFieldLabel        matlab.ui.control.Label
        GroundStationsTab               matlab.ui.container.Tab
        CreateStationsButton            matlab.ui.control.Button
        FileSelectedTextArea            matlab.ui.control.TextArea
        FileSelectedTextAreaLabel       matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        LinkTab                         matlab.ui.container.Tab
        ModulationIndexEditField        matlab.ui.control.NumericEditField
        ModulationIndexEditFieldLabel   matlab.ui.control.Label
        ModulationOrderListBox          matlab.ui.control.ListBox
        ModulationOrderListBoxLabel     matlab.ui.control.Label
        ModulationSchemeListBox         matlab.ui.control.ListBox
        ModulationSchemeListBoxLabel    matlab.ui.control.Label
        TransmitterGainEditField        matlab.ui.control.NumericEditField
        TransmitterGainEditFieldLabel   matlab.ui.control.Label
        SignalPowerEditField            matlab.ui.control.NumericEditField
        SignalPowerEditFieldLabel       matlab.ui.control.Label
        ChannelBandwidthEditField       matlab.ui.control.NumericEditField
        ChannelBandwidthEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        AccessTab                       matlab.ui.container.Tab
        SimulateAccessButton            matlab.ui.control.Button
        CreateAccessButton              matlab.ui.control.Button
        DestinationAirportDropDown      matlab.ui.control.DropDown
        DestinationAirportDropDownLabel  matlab.ui.control.Label
        StartingAirportDropDown         matlab.ui.control.DropDown
        StartingAirportDropDownLabel    matlab.ui.control.Label
        CreateAircraftButton            matlab.ui.control.Button
        DisplayTypeListBox              matlab.ui.control.ListBox
        DisplayTypeListBoxLabel         matlab.ui.control.Label
        DestinationCountryDropDown      matlab.ui.control.DropDown
        DestinationCountryDropDownLabel  matlab.ui.control.Label
        StartingCountryDropDown         matlab.ui.control.DropDown
        StartingCountryDropDownLabel    matlab.ui.control.Label
        PauseTab                        matlab.ui.container.Tab
        PauseButton                     matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        CheckButton                     matlab.ui.control.Button
        DebugTextArea                   matlab.ui.control.TextArea
        DebugTextAreaLabel              matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    properties (Constant)
        airports = readtable("iata-icao.csv");
    end

    properties (Access = private)
        % Values
        countrycode_list = unique(apptest.airports.country_code);
        startcountry;
        startlat;
        startlong;
        endcountry;
        endlat;
        endlong;
        simstart;
        simduration;
        simsteptime;

        % Objects
        scenario;
        constellation;
        aircraft;
        access;
        selective;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartingCountryDropDown.Items = app.countrycode_list;
            app.DestinationCountryDropDown.Items = app.countrycode_list;
        end

        % Value changed function: StartingAirportDropDown
        function StartingAirportDropDownValueChanged(app, event)
            %value = app.StartingAirportDropDown.Value;
            
        end

        % Value changed function: StartingCountryDropDown
        function StartingCountryDropDownValueChanged(app, event)
            app.startcountry = string(app.countrycode_list(app.StartingCountryDropDown.ValueIndex));
            start_airport_list = app.airports(ismember(app.airports.country_code, app.startcountry), :);
            start_airport_names_list = table2cell([start_airport_list(:, [2 3])]);
            app.StartingAirportDropDown.Items = start_airport_names_list(:, 2);
        end

        % Drop down opening function: StartingCountryDropDown
        function StartingCountryDropDownOpening(app, event)
            
        end

        % Value changed function: DestinationCountryDropDown
        function DestinationCountryDropDownValueChanged(app, event)
            app.endcountry = string(app.countrycode_list(app.DestinationCountryDropDown.ValueIndex));
            end_airport_list = app.airports(ismember(app.airports.country_code, app.endcountry), :);
            end_airport_names_list = table2cell([end_airport_list(:, [2 3])]);
            app.DestinationAirportDropDown.Items = end_airport_names_list(:, 2);
        end

        % Button pushed function: CreateAircraftButton
        function CreateAircraftButtonPushed(app, event)
            start_airport_list = app.airports(ismember(app.airports.country_code, app.startcountry), :);
            end_airport_list = app.airports(ismember(app.airports.country_code, app.endcountry), :);
            
            app.startlat = table2array(start_airport_list(app.StartingAirportDropDown.ValueIndex, 4));
            app.startlong = table2array(start_airport_list(app.StartingAirportDropDown.ValueIndex, 5));
            app.endlat = table2array(end_airport_list(app.DestinationAirportDropDown.ValueIndex, 4));
            app.endlong = table2array(end_airport_list(app.DestinationAirportDropDown.ValueIndex, 5));
            
            app.aircraft = create_mission(app.scenario, app.simstart, app.simduration, app.startlat, app.startlong, app.endlat, app.endlong);
        end

        % Value changed function: DestinationAirportDropDown
        function DestinationAirportDropDownValueChanged(app, event)
            %value = app.DestinationAirportDropDown.Value;
        end

        % Value changed function: StartYearEditField
        function StartYearEditFieldValueChanged(app, event)
            value = app.StartYearEditField.Value;
            
        end

        % Button pushed function: CreateSimulationButton
        function CreateSimulationButtonPushed(app, event)
            simyear = app.StartYearEditField.Value;
            simmonth = app.StartMonthEditField.Value;
            simday = app.StartDayEditField.Value;
            simhour = app.StartHourEditField.Value;
            simmin = app.StartMinuteEditField.Value;
            app.simstart = datetime(simyear, simmonth, simday, simhour, simmin, 0);

            simdurationhour = app.DurationHourEditField.Value;
            simdurationminute = app.DurationMinutesEditField.Value;
            app.simduration = duration(simdurationhour, simdurationminute, 0);

            app.simsteptime = app.StepTimeEditField.Value;
            app.scenario = create_scenario(app.simstart, app.simduration, app.simsteptime);
        end

        % Button pushed function: CreateSatellitesButton
        function CreateSatellitesButtonPushed(app, event)
            app.constellation = walker_constellation_v2(app.scenario.scenario, app.SatellitesEditField.Value, app.PlanesEditField.Value, app.RelativeSpacingEditField.Value, app.AltitudeEditField.Value, app.InclinationEditField.Value, convertCharsToStrings(app.WalkerTypeListBox.Items));
        end

        % Button pushed function: CreateAccessButton
        function CreateAccessButtonPushed(app, event)
            [app.access, app.selective] = AccessAnalysis(app.scenario, app.aircraft, app.constellation);
        end

        % Button pushed function: SimulateAccessButton
        function SimulateAccessButtonPushed(app, event)
            if app.DisplayTypeListBox.Value == convertStringsToChars('Red/Green')
                accessSim_red_green(app.scenario, app.selective, app.access)
            end
            if app.DisplayTypeListBox.Value == convertStringsToChars('Connected')
                accessSim_only_connected(app.scenario, app.selective, app.access)
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pausewarn = warndlg("Paused");
            waitfor(pausewarn)
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            app.DebugTextArea.Value = string(59.6754/200);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            groundfile = uigetfile();
            [x,name,y] = fileparts(groundfile);
            app.FileSelectedTextArea.Value = name;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.StartYearEditFieldLabel = uilabel(app.SimulationTab);
            app.StartYearEditFieldLabel.Text = 'Start Year';
            app.StartYearEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StartYearEditField.ValueChangedFcn = createCallbackFcn(app, @StartYearEditFieldValueChanged, true);
            app.StartMonthEditFieldLabel = uilabel(app.SimulationTab);
            app.StartMonthEditFieldLabel.Text = 'Start Month';
            app.StartMonthEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StartDayEditFieldLabel = uilabel(app.SimulationTab);
            app.StartDayEditFieldLabel.Text = 'Start Day';
            app.StartDayEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StartHourEditFieldLabel = uilabel(app.SimulationTab);
            app.StartHourEditFieldLabel.Text = 'Start Hour';
            app.StartHourEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StartMinuteEditFieldLabel = uilabel(app.SimulationTab);
            app.StartMinuteEditFieldLabel.Text = 'Start Minute';
            app.StartMinuteEditField = uieditfield(app.SimulationTab, 'numeric');
            app.DurationHourEditFieldLabel = uilabel(app.SimulationTab);
            app.DurationHourEditFieldLabel.Text = 'Duration Hour';
            app.DurationHourEditField = uieditfield(app.SimulationTab, 'numeric');
            app.DurationMinutesEditFieldLabel = uilabel(app.SimulationTab);
            app.DurationMinutesEditFieldLabel.Text = 'Duration Minutes';
            app.DurationMinutesEditField = uieditfield(app.SimulationTab, 'numeric');
            app.CreateSimulationButton = uibutton(app.SimulationTab, 'push');
            app.CreateSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSimulationButtonPushed, true);
            app.CreateSimulationButton.Text = 'Create Simulation';
            app.StepTimeEditFieldLabel = uilabel(app.SimulationTab);
            app.StepTimeEditFieldLabel.Text = 'Step Time';
            app.StepTimeEditField = uieditfield(app.SimulationTab, 'numeric');
            app.SatellitesTab = uitab(app.TabGroup);
            app.SatellitesTab.Title = 'Satellites';
            app.SatellitesEditFieldLabel = uilabel(app.SatellitesTab);
            app.SatellitesEditFieldLabel.Text = 'Satellites';
            app.SatellitesEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.PlanesEditFieldLabel = uilabel(app.SatellitesTab);
            app.PlanesEditFieldLabel.Text = 'Planes';
            app.PlanesEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.RelativeSpacingEditFieldLabel = uilabel(app.SatellitesTab);
            app.RelativeSpacingEditFieldLabel.Text = 'Relative Spacing';
            app.RelativeSpacingEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.AltitudeEditFieldLabel = uilabel(app.SatellitesTab);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.InclinationEditFieldLabel = uilabel(app.SatellitesTab);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.SatellitesTab, 'numeric');
            app.WalkerTypeListBoxLabel = uilabel(app.SatellitesTab);
            app.WalkerTypeListBoxLabel.Text = 'Walker Type';
            app.WalkerTypeListBox = uilistbox(app.SatellitesTab);
            app.WalkerTypeListBox.Items = {'delta', 'star'};
            app.WalkerTypeListBox.Value = 'delta';
            app.CreateSatellitesButton = uibutton(app.SatellitesTab, 'push');
            app.CreateSatellitesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSatellitesButtonPushed, true);
            app.CreateSatellitesButton.Text = 'Create Satellites';
            app.GroundStationsTab = uitab(app.TabGroup);
            app.GroundStationsTab.Title = 'Ground Stations';
            app.UploadButton = uibutton(app.GroundStationsTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FileSelectedTextAreaLabel = uilabel(app.GroundStationsTab);
            app.FileSelectedTextAreaLabel.Text = 'File Selected';
            app.FileSelectedTextArea = uitextarea(app.GroundStationsTab);
            app.CreateStationsButton = uibutton(app.GroundStationsTab, 'push');
            app.CreateStationsButton.Text = 'Create Stations';
            app.LinkTab = uitab(app.TabGroup);
            app.LinkTab.Title = 'Link';
            app.CarrierFrequencyEditFieldLabel = uilabel(app.LinkTab);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.LinkTab, 'numeric');
            app.ChannelBandwidthEditFieldLabel = uilabel(app.LinkTab);
            app.ChannelBandwidthEditFieldLabel.Text = 'Channel Bandwidth';
            app.ChannelBandwidthEditField = uieditfield(app.LinkTab, 'numeric');
            app.SignalPowerEditFieldLabel = uilabel(app.LinkTab);
            app.SignalPowerEditFieldLabel.Text = 'Signal Power';
            app.SignalPowerEditField = uieditfield(app.LinkTab, 'numeric');
            app.TransmitterGainEditFieldLabel = uilabel(app.LinkTab);
            app.TransmitterGainEditFieldLabel.Text = 'Transmitter Gain';
            app.TransmitterGainEditField = uieditfield(app.LinkTab, 'numeric');
            app.ModulationSchemeListBoxLabel = uilabel(app.LinkTab);
            app.ModulationSchemeListBoxLabel.Text = 'Modulation Scheme';
            app.ModulationSchemeListBox = uilistbox(app.LinkTab);
            app.ModulationSchemeListBox.Items = {'QAM', 'PSK', 'FSK'};
            app.ModulationSchemeListBox.Value = 'QAM';
            app.ModulationOrderListBoxLabel = uilabel(app.LinkTab);
            app.ModulationOrderListBoxLabel.Text = 'Modulation Order';
            app.ModulationOrderListBox = uilistbox(app.LinkTab);
            app.ModulationOrderListBox.Items = {'2', '4', '8', '16', '32'};
            app.ModulationOrderListBox.Value = '2';
            app.ModulationIndexEditFieldLabel = uilabel(app.LinkTab);
            app.ModulationIndexEditFieldLabel.Text = 'Modulation Index';
            app.ModulationIndexEditField = uieditfield(app.LinkTab, 'numeric');
            app.AccessTab = uitab(app.TabGroup);
            app.AccessTab.Title = 'Access';
            app.StartingCountryDropDownLabel = uilabel(app.AccessTab);
            app.StartingCountryDropDownLabel.Text = 'Starting Country';
            app.StartingCountryDropDown = uidropdown(app.AccessTab);
            app.StartingCountryDropDown.Items = {};
            app.StartingCountryDropDown.Editable = 'on';
            app.StartingCountryDropDown.DropDownOpeningFcn = createCallbackFcn(app, @StartingCountryDropDownOpening, true);
            app.StartingCountryDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingCountryDropDownValueChanged, true);
            app.StartingCountryDropDown.Value = {};
            app.DestinationCountryDropDownLabel = uilabel(app.AccessTab);
            app.DestinationCountryDropDownLabel.Text = 'Destination Country';
            app.DestinationCountryDropDown = uidropdown(app.AccessTab);
            app.DestinationCountryDropDown.Items = {};
            app.DestinationCountryDropDown.Editable = 'on';
            app.DestinationCountryDropDown.ValueChangedFcn = createCallbackFcn(app, @DestinationCountryDropDownValueChanged, true);
            app.DestinationCountryDropDown.Value = {};
            app.DisplayTypeListBoxLabel = uilabel(app.AccessTab);
            app.DisplayTypeListBoxLabel.Text = 'Display Type';
            app.DisplayTypeListBox = uilistbox(app.AccessTab);
            app.DisplayTypeListBox.Items = {'Red/Green', 'Connected'};
            app.DisplayTypeListBox.Value = {};
            app.CreateAircraftButton = uibutton(app.AccessTab, 'push');
            app.CreateAircraftButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAircraftButtonPushed, true);
            app.CreateAircraftButton.Text = 'Create Aircraft';
            app.StartingAirportDropDownLabel = uilabel(app.AccessTab);
            app.StartingAirportDropDownLabel.Text = 'Starting Airport';
            app.StartingAirportDropDown = uidropdown(app.AccessTab);
            app.StartingAirportDropDown.Items = {};
            app.StartingAirportDropDown.Editable = 'on';
            app.StartingAirportDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingAirportDropDownValueChanged, true);
            app.StartingAirportDropDown.Value = {};
            app.DestinationAirportDropDownLabel = uilabel(app.AccessTab);
            app.DestinationAirportDropDownLabel.Text = 'Destination Airport';
            app.DestinationAirportDropDown = uidropdown(app.AccessTab);
            app.DestinationAirportDropDown.Items = {};
            app.DestinationAirportDropDown.Editable = 'on';
            app.DestinationAirportDropDown.ValueChangedFcn = createCallbackFcn(app, @DestinationAirportDropDownValueChanged, true);
            app.DestinationAirportDropDown.Value = {};
            app.CreateAccessButton = uibutton(app.AccessTab, 'push');
            app.CreateAccessButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccessButtonPushed, true);
            app.CreateAccessButton.Text = 'Create Access';
            app.SimulateAccessButton = uibutton(app.AccessTab, 'push');
            app.SimulateAccessButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateAccessButtonPushed, true);
            app.SimulateAccessButton.Text = 'Simulate Access';
            app.PauseTab = uitab(app.TabGroup);
            app.PauseTab.Title = 'Pause';
            app.PauseButton = uibutton(app.PauseTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.DebugTextAreaLabel = uilabel(app.Tab);
            app.DebugTextAreaLabel.Text = 'Debug';
            app.DebugTextArea = uitextarea(app.Tab);
            app.CheckButton = uibutton(app.Tab, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end